classdef final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FindJobsTab                     matlab.ui.container.Tab
        HighestLevelofEducationDropDownLabel  matlab.ui.control.Label
        HighestLevelofEducationDropDown  matlab.ui.control.DropDown
        EnteryourinformationandwellletyouknowwhatjobswouldsuityouLabel  matlab.ui.control.Label
        SearchButton                    matlab.ui.control.Button
        MatchingJobsLabel               matlab.ui.control.Label
        MatchingJobsTextArea            matlab.ui.control.TextArea
        SalaryBracketDropDownLabel      matlab.ui.control.Label
        SalaryBracketDropDown           matlab.ui.control.DropDown
        InterestsLabel                  matlab.ui.control.Label
        BusinessCheckBox                matlab.ui.control.CheckBox
        SalesCheckBox                   matlab.ui.control.CheckBox
        LawCheckBox                     matlab.ui.control.CheckBox
        EducationServiceCheckBox        matlab.ui.control.CheckBox
        HealthcareCheckBox              matlab.ui.control.CheckBox
        FoodPersonalCareCheckBox        matlab.ui.control.CheckBox
        ProtectiveServicesCheckBox      matlab.ui.control.CheckBox
        EngineeringMathComputersCheckBox  matlab.ui.control.CheckBox
        FarmingFishingForestingCheckBox  matlab.ui.control.CheckBox
        ConstructionMaintenanceManufacturingCheckBox  matlab.ui.control.CheckBox
        ArtsEntertainmentSportsetcCheckBox  matlab.ui.control.CheckBox
        MilitaryCheckBox                matlab.ui.control.CheckBox
        GetMoreInformationTab           matlab.ui.container.Tab
        EnterJobTitleEditFieldLabel     matlab.ui.control.Label
        EnterJobTitleEditField          matlab.ui.control.EditField
        Employment2016thousandsEditFieldLabel  matlab.ui.control.Label
        Employment2016thousandsEditField  matlab.ui.control.EditField
        Employment2026thousandsEditFieldLabel  matlab.ui.control.Label
        Employment2026thousandsEditField  matlab.ui.control.EditField
        EmploymentChange20162026percentEditFieldLabel  matlab.ui.control.Label
        EmploymentChange20162026percentEditField  matlab.ui.control.EditField
        OccupationalOpenings20162026annualaveragethousandsLabel  matlab.ui.control.Label
        OccupationalOpenings20162026annualaveragethousandsEditField  matlab.ui.control.EditField
        TypicalEntryLevelEducationLabel  matlab.ui.control.Label
        TypicalEntryLevelEducationEditField  matlab.ui.control.EditField
        TypicalWorkExperienceLabel      matlab.ui.control.Label
        TypicalWorkExperienceEditField  matlab.ui.control.EditField
        TypicalonthejobtrainingLabel    matlab.ui.control.Label
        TypicalonthejobtrainingEditField  matlab.ui.control.EditField
        GoButton                        matlab.ui.control.Button
        ExampleJobsLabel                matlab.ui.control.Label
        ExampleJobsTextArea             matlab.ui.control.TextArea
        MedianAnnualWage2017Label       matlab.ui.control.Label
        MedianAnnualWage2017EditField   matlab.ui.control.EditField
        SavethisJobsInformationButton   matlab.ui.control.Button
        ClearSavedJobsButton            matlab.ui.control.Button
        UnemploymentStatisticsTab       matlab.ui.container.Tab
        PlotofUnemploymentRatesintheUSLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        GraphUnemploymentButton         matlab.ui.control.Button
    end


    properties (Access = public)
        data = csv_processing(); % data from csv file
        subset_data; % will hold data with criteria applied (OR WILL FAIL)
        edu_level = 8; % a code (1 - 8) describing level of education
        interests = []; % vector of ints that code for interests
        search_results; % cell array with one row: has all the data for one job
        salary_bracket = [15000 30000]; % min and max salary
        xdata; % x data for plots (time)
        ydata; % unemployment data 
        key1 = ""; % words for user search function 
        key2 = "";
        key3 = "";
    end


    methods (Access = private)

        % Value changed function: HighestLevelofEducationDropDown
        function HighestLevelofEducationDropDownValueChanged(app, event)
            value = app.HighestLevelofEducationDropDown.Value;
            if (strcmp(value, "No Formal Education"))
                app.edu_level = 8;
            elseif (strcmp(value, "High School Diploma or Equivalent"))
                app.edu_level = 7;
            elseif (strcmp(value, "Some College, No Degree"))
                app.edu_level = 6;
            elseif (strcmp(value, "Associate's Degree"))
                app.edu_level = 5; 
            elseif (strcmp(value, "Postsecondary Nondegree Award"))
                app.edu_level = 4;
            elseif (strcmp(value, "Bachelor's Degree"))
                app.edu_level = 3;
            elseif (strcmp(value, "Master's Degree"))
                app.edu_level = 2;
            elseif (strcmp(value, "Doctoral/Professional Degree"))
                app.edu_level = 1;
            end
            
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            app.subset_data = criteria_search(app.data, app.edu_level, app.salary_bracket(1), app.salary_bracket(2), app.interests);
            titles = find_titles(app.subset_data); 
            app.MatchingJobsTextArea.Value = titles;
            %app.MatchingJobsTextArea.Value = {num2str(app.edu_level), num2str(app.salary_bracket(1)), num2str(app.interests)};
        end

        % Value changed function: SalaryBracketDropDown
        function SalaryBracketDropDownValueChanged(app, event)
            value = app.SalaryBracketDropDown.Value;
            if (strcmp(value, "$15k-30k"))
                app.salary_bracket = [15000 30000];
            elseif (strcmp(value, "$30k-50k"))
                app.salary_bracket = [30000 50000];
            elseif (strcmp(value, "$50k-100k"))
                app.salary_bracket = [50000 100000];
            elseif (strcmp(value, "$100k-150k"))
                app.salary_bracket = [100000 150000];
            elseif (strcmp(value, "$150k+"))
                app.salary_bracket = [150000 100000000];
            end
        end

        % Value changed function: BusinessCheckBox
        function BusinessCheckBoxValueChanged(app, event)
            value = app.BusinessCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 1;
            end
        end

        % Value changed function: SalesCheckBox
        function SalesCheckBoxValueChanged(app, event)
            value = app.SalesCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 2;
            end
        end

        % Value changed function: LawCheckBox
        function LawCheckBoxValueChanged(app, event)
            value = app.LawCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 3;
            end
        end

        % Value changed function: MilitaryCheckBox
        function MilitaryCheckBoxValueChanged(app, event)
            value = app.MilitaryCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 4;
            end
        end

        % Value changed function: EducationServiceCheckBox
        function EducationServiceCheckBoxValueChanged(app, event)
            value = app.EducationServiceCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 5;
            end
        end

        % Value changed function: HealthcareCheckBox
        function HealthcareCheckBoxValueChanged(app, event)
            value = app.HealthcareCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 6;
            end
        end

        % Value changed function: FoodPersonalCareCheckBox
        function FoodPersonalCareCheckBoxValueChanged(app, event)
            value = app.FoodPersonalCareCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 7;
            end
        end

        % Value changed function: ProtectiveServicesCheckBox
        function ProtectiveServicesCheckBoxValueChanged(app, event)
            value = app.ProtectiveServicesCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 8;
            end
        end

        % Value changed function: EngineeringMathComputersCheckBox
        function EngineeringMathComputersCheckBoxValueChanged(app, event)
            value = app.EngineeringMathComputersCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 9;
            end
        end

        % Value changed function: FarmingFishingForestingCheckBox
        function FarmingFishingForestingCheckBoxValueChanged(app, event)
            value = app.FarmingFishingForestingCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 10;
            end
        end

        % Value changed function: 
        % ConstructionMaintenanceManufacturingCheckBox
        function ConstructionMaintenanceManufacturingCheckBoxValueChanged(app, event)
            value = app.ConstructionMaintenanceManufacturingCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 11;
            end
        end

        % Value changed function: ArtsEntertainmentSportsetcCheckBox
        function ArtsEntertainmentSportsetcCheckBoxValueChanged(app, event)
            value = app.ArtsEntertainmentSportsetcCheckBox.Value;
            if (value == 1)
                size = length(app.interests);
                app.interests(size+1) = 12;
            end
        end

        % Value changed function: EnterJobTitleEditField
        function EnterJobTitleEditFieldValueChanged(app, event)
            value = app.EnterJobTitleEditField.Value;
            %app.search_results = find_by_search(app.data, [value]); % works!!
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            value = app.EnterJobTitleEditField.Value;
            if ~exist("user_searches.txt",'file')
                init_user_search_file();
            end
            app.search_results = find_by_search(app.data, value);
            job = app.search_results;
            app.Employment2016thousandsEditField.Value = num2str(job{1,3});
            app.Employment2026thousandsEditField.Value = num2str(job{1,4});
            app.EmploymentChange20162026percentEditField.Value = num2str(job{1,5});
            app.OccupationalOpenings20162026annualaveragethousandsEditField.Value = num2str(job{1,6});
            %app.MedianAnnualWageEditField.Value = job{1,7};
            app.MedianAnnualWage2017EditField.Value = num2str(job{1,7});
            %education
            if (job{1,8} == 1)
                app.TypicalEntryLevelEducationEditField.Value = "Doctoral/Professional Degree";
            elseif (job{1,8} == 2)
                app.TypicalEntryLevelEducationEditField.Value = "Master's Degree";
            elseif (job{1,8} == 3)
                app.TypicalEntryLevelEducationEditField.Value = "Bachelor's Degree";
            elseif (job{1,8} == 4)
                app.TypicalEntryLevelEducationEditField.Value = "Postsecondary Nondegree award";
            elseif (job{1,8} == 5)
                app.TypicalEntryLevelEducationEditField.Value = "Associate's Degree";
            elseif (job{1,8} == 6)
                app.TypicalEntryLevelEducationEditField.Value = "Some College, No Degree";
            elseif (job{1,8} == 7)
                app.TypicalEntryLevelEducationEditField.Value = "High School Diploma or Equivalent";
            elseif (job{1,8} == 8)
                app.TypicalEntryLevelEducationEditField.Value = "No Formal Education";
            end
            %work experience
            if (job{1,9} == 1)
                app.TypicalWorkExperienceEditField.Value = "5 years or more";
            elseif (job{1,9} == 2)
                app.TypicalWorkExperienceEditField.Value = "Less than 5 years";
            elseif (job{1,9} == 4)
                app.TypicalWorkExperienceEditField.Value = "Less than 5 years";
            end
            % on the job experience
            if (job{1,10} == 1)
                app.TypicalonthejobtrainingEditField.Value = "Internship/Residency";
            elseif (job{1,10} == 2)
                app.TypicalonthejobtrainingEditField.Value = "Apprenticeship";
            elseif (job{1,10} == 3)
                app.TypicalonthejobtrainingEditField.Value = "Long-term on the job training";
            elseif (job{1,10} == 4)
                app.TypicalonthejobtrainingEditField.Value = "Moderate on the job training";
            elseif (job{1,10} == 5)
                app.TypicalonthejobtrainingEditField.Value = "Short-term on the job training";
            elseif (job{1,10} == 6)
                app.TypicalonthejobtrainingEditField.Value = "None";
            end
            app.ExampleJobsTextArea.Value = get_examples(job{1,1});
            
            app.SavethisJobsInformationButton.Text = "Save this Job's Information";
            app.ClearSavedJobsButton.Text = "Clear Saved Jobs";
                            
        end

        % Button pushed function: SavethisJobsInformationButton
        function SavethisJobsInformationButtonPushed(app, event)
            writetofile(app.search_results);
            app.SavethisJobsInformationButton.Text = "Information saved!";
        end

        % Button pushed function: ClearSavedJobsButton
        function ClearSavedJobsButtonPushed(app, event)
            clear_user_search_file();
            app.ClearSavedJobsButton.Text = "Job's Cleared!";
        end

        % Button pushed function: GraphUnemploymentButton
        function GraphUnemploymentButtonPushed(app, event)
            [app.xdata, xtlabs] = get_graph_data(); 
            app.ydata = 1:length(xtlabs);
            plot(app.UIAxes, app.xdata, app.ydata);
            app.UIAxes.XTickLabel = xtlabs;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FindJobsTab = uitab(app.TabGroup);
            app.FindJobsTab.Title = 'Find Jobs';
            app.HighestLevelofEducationDropDownLabel = uilabel(app.FindJobsTab);
            app.HighestLevelofEducationDropDownLabel.Text = 'Highest Level of Education';
            app.HighestLevelofEducationDropDown = uidropdown(app.FindJobsTab);
            app.HighestLevelofEducationDropDown.Items = {'No Formal Education', 'High School Diploma or Equivalent', 'Some College, No Degree', 'Associate''s Degree', 'Postsecondary Nondegree Award', 'Bachelor''s Degree', 'Master''s Degree', 'Doctoral/Professional Degree'};
            app.HighestLevelofEducationDropDown.ValueChangedFcn = createCallbackFcn(app, @HighestLevelofEducationDropDownValueChanged, true);
            app.HighestLevelofEducationDropDown.Value = 'No Formal Education';
            app.EnteryourinformationandwellletyouknowwhatjobswouldsuityouLabel = uilabel(app.FindJobsTab);
            app.EnteryourinformationandwellletyouknowwhatjobswouldsuityouLabel.Text = 'Enter your information and we''ll let you know what jobs would suit you!';
            app.SearchButton = uibutton(app.FindJobsTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search!';
            app.MatchingJobsLabel = uilabel(app.FindJobsTab);
            app.MatchingJobsLabel.Text = 'Matching Jobs:';
            app.MatchingJobsTextArea = uitextarea(app.FindJobsTab);
            app.SalaryBracketDropDownLabel = uilabel(app.FindJobsTab);
            app.SalaryBracketDropDownLabel.Text = 'Salary Bracket';
            app.SalaryBracketDropDown = uidropdown(app.FindJobsTab);
            app.SalaryBracketDropDown.Items = {'$15-30k', '$30k-50k', '$50k-100k', '$100k-150k', '$150k+'};
            app.SalaryBracketDropDown.ValueChangedFcn = createCallbackFcn(app, @SalaryBracketDropDownValueChanged, true);
            app.SalaryBracketDropDown.Value = '$15-30k';
            app.InterestsLabel = uilabel(app.FindJobsTab);
            app.InterestsLabel.Text = 'Interests';
            app.BusinessCheckBox = uicheckbox(app.FindJobsTab);
            app.BusinessCheckBox.ValueChangedFcn = createCallbackFcn(app, @BusinessCheckBoxValueChanged, true);
            app.BusinessCheckBox.Text = 'Business';
            app.SalesCheckBox = uicheckbox(app.FindJobsTab);
            app.SalesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SalesCheckBoxValueChanged, true);
            app.SalesCheckBox.Text = 'Sales';
            app.LawCheckBox = uicheckbox(app.FindJobsTab);
            app.LawCheckBox.ValueChangedFcn = createCallbackFcn(app, @LawCheckBoxValueChanged, true);
            app.LawCheckBox.Text = 'Law';
            app.EducationServiceCheckBox = uicheckbox(app.FindJobsTab);
            app.EducationServiceCheckBox.ValueChangedFcn = createCallbackFcn(app, @EducationServiceCheckBoxValueChanged, true);
            app.EducationServiceCheckBox.Text = 'Education & Service';
            app.HealthcareCheckBox = uicheckbox(app.FindJobsTab);
            app.HealthcareCheckBox.ValueChangedFcn = createCallbackFcn(app, @HealthcareCheckBoxValueChanged, true);
            app.HealthcareCheckBox.Text = 'Healthcare';
            app.FoodPersonalCareCheckBox = uicheckbox(app.FindJobsTab);
            app.FoodPersonalCareCheckBox.ValueChangedFcn = createCallbackFcn(app, @FoodPersonalCareCheckBoxValueChanged, true);
            app.FoodPersonalCareCheckBox.Text = 'Food & Personal Care';
            app.ProtectiveServicesCheckBox = uicheckbox(app.FindJobsTab);
            app.ProtectiveServicesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProtectiveServicesCheckBoxValueChanged, true);
            app.ProtectiveServicesCheckBox.Text = 'Protective Services';
            app.EngineeringMathComputersCheckBox = uicheckbox(app.FindJobsTab);
            app.EngineeringMathComputersCheckBox.ValueChangedFcn = createCallbackFcn(app, @EngineeringMathComputersCheckBoxValueChanged, true);
            app.EngineeringMathComputersCheckBox.Text = 'Engineering, Math, Computers';
            app.FarmingFishingForestingCheckBox = uicheckbox(app.FindJobsTab);
            app.FarmingFishingForestingCheckBox.ValueChangedFcn = createCallbackFcn(app, @FarmingFishingForestingCheckBoxValueChanged, true);
            app.FarmingFishingForestingCheckBox.Text = 'Farming, Fishing, Foresting';
            app.ConstructionMaintenanceManufacturingCheckBox = uicheckbox(app.FindJobsTab);
            app.ConstructionMaintenanceManufacturingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConstructionMaintenanceManufacturingCheckBoxValueChanged, true);
            app.ConstructionMaintenanceManufacturingCheckBox.Text = 'Construction, Maintenance, Manufacturing';
            app.ArtsEntertainmentSportsetcCheckBox = uicheckbox(app.FindJobsTab);
            app.ArtsEntertainmentSportsetcCheckBox.ValueChangedFcn = createCallbackFcn(app, @ArtsEntertainmentSportsetcCheckBoxValueChanged, true);
            app.ArtsEntertainmentSportsetcCheckBox.Text = 'Arts, Entertainment, Sports, etc.';
            app.MilitaryCheckBox = uicheckbox(app.FindJobsTab);
            app.MilitaryCheckBox.ValueChangedFcn = createCallbackFcn(app, @MilitaryCheckBoxValueChanged, true);
            app.MilitaryCheckBox.Text = 'Military';
            app.GetMoreInformationTab = uitab(app.TabGroup);
            app.GetMoreInformationTab.Title = 'Get More Information';
            app.EnterJobTitleEditFieldLabel = uilabel(app.GetMoreInformationTab);
            app.EnterJobTitleEditFieldLabel.Text = 'Enter Job Title:';
            app.EnterJobTitleEditField = uieditfield(app.GetMoreInformationTab, 'text');
            app.EnterJobTitleEditField.ValueChangedFcn = createCallbackFcn(app, @EnterJobTitleEditFieldValueChanged, true);
            app.Employment2016thousandsEditFieldLabel = uilabel(app.GetMoreInformationTab);
            app.Employment2016thousandsEditFieldLabel.Text = 'Employment 2016 (thousands):';
            app.Employment2016thousandsEditField = uieditfield(app.GetMoreInformationTab, 'text');
            app.Employment2026thousandsEditFieldLabel = uilabel(app.GetMoreInformationTab);
            app.Employment2026thousandsEditFieldLabel.Text = 'Employment 2026 (thousands):';
            app.Employment2026thousandsEditField = uieditfield(app.GetMoreInformationTab, 'text');
            app.EmploymentChange20162026percentEditFieldLabel = uilabel(app.GetMoreInformationTab);
            app.EmploymentChange20162026percentEditFieldLabel.Text = 'Employment Change 2016-2026 (percent):';
            app.EmploymentChange20162026percentEditField = uieditfield(app.GetMoreInformationTab, 'text');
            app.OccupationalOpenings20162026annualaveragethousandsLabel = uilabel(app.GetMoreInformationTab);
            app.OccupationalOpenings20162026annualaveragethousandsLabel.Text = 'Occupational Openings, 2016-2026 annual average (thousands):';
            app.OccupationalOpenings20162026annualaveragethousandsEditField = uieditfield(app.GetMoreInformationTab, 'text');
            app.TypicalEntryLevelEducationLabel = uilabel(app.GetMoreInformationTab);
            app.TypicalEntryLevelEducationLabel.Text = 'Typical Entry Level Education:';
            app.TypicalEntryLevelEducationEditField = uieditfield(app.GetMoreInformationTab, 'text');
            app.TypicalWorkExperienceLabel = uilabel(app.GetMoreInformationTab);
            app.TypicalWorkExperienceLabel.Text = 'Typical Work Experience:';
            app.TypicalWorkExperienceEditField = uieditfield(app.GetMoreInformationTab, 'text');
            app.TypicalonthejobtrainingLabel = uilabel(app.GetMoreInformationTab);
            app.TypicalonthejobtrainingLabel.Text = 'Typical on-the-job training:';
            app.TypicalonthejobtrainingEditField = uieditfield(app.GetMoreInformationTab, 'text');
            app.GoButton = uibutton(app.GetMoreInformationTab, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.ExampleJobsLabel = uilabel(app.GetMoreInformationTab);
            app.ExampleJobsLabel.Text = 'Example Jobs:';
            app.ExampleJobsTextArea = uitextarea(app.GetMoreInformationTab);
            app.MedianAnnualWage2017Label = uilabel(app.GetMoreInformationTab);
            app.MedianAnnualWage2017Label.Text = 'Median Annual Wage (2017):';
            app.MedianAnnualWage2017EditField = uieditfield(app.GetMoreInformationTab, 'text');
            app.SavethisJobsInformationButton = uibutton(app.GetMoreInformationTab, 'push');
            app.SavethisJobsInformationButton.ButtonPushedFcn = createCallbackFcn(app, @SavethisJobsInformationButtonPushed, true);
            app.SavethisJobsInformationButton.Text = 'Save this Job''s Information!';
            app.ClearSavedJobsButton = uibutton(app.GetMoreInformationTab, 'push');
            app.ClearSavedJobsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSavedJobsButtonPushed, true);
            app.ClearSavedJobsButton.Text = 'Clear Saved Jobs';
            app.UnemploymentStatisticsTab = uitab(app.TabGroup);
            app.UnemploymentStatisticsTab.Title = 'Unemployment Statistics';
            app.PlotofUnemploymentRatesintheUSLabel = uilabel(app.UnemploymentStatisticsTab);
            app.PlotofUnemploymentRatesintheUSLabel.Text = 'Plot of Unemployment Rates in the US';
            app.UIAxes = uiaxes(app.UnemploymentStatisticsTab);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Number of Unemployed Americans')
            app.UIAxes.XLim = [0 27];
            app.UIAxes.YLim = [0 10000];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.GraphUnemploymentButton = uibutton(app.UnemploymentStatisticsTab, 'push');
            app.GraphUnemploymentButton.ButtonPushedFcn = createCallbackFcn(app, @GraphUnemploymentButtonPushed, true);
            app.GraphUnemploymentButton.Text = 'Graph Unemployment';
        end
    end

    methods (Access = public)

        % Construct app
        function app = final

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end